classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        Group5Label                matlab.ui.control.Label
        TRAFFICSIGNDETECTORLabel   matlab.ui.control.Label
        UploadImageButton          matlab.ui.control.Button
        UIAxes2                    matlab.ui.control.UIAxes
        CenterPanel                matlab.ui.container.Panel
        Slider                     matlab.ui.control.Slider
        Label                      matlab.ui.control.Label
        SelectMaskButton           matlab.ui.control.Button
        SelecttheBinaryMaskthatbesthighlightthetrafficsignLabel  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
        MeaningTextArea            matlab.ui.control.TextArea
        MeaningTextAreaLabel       matlab.ui.control.Label
        NameCategoryTextArea       matlab.ui.control.TextArea
        NameCategoryTextAreaLabel  matlab.ui.control.Label
        ShapeTextArea              matlab.ui.control.TextArea
        ShapeTextAreaLabel         matlab.ui.control.Label
        ColorTextArea              matlab.ui.control.TextArea
        ColorTextAreaLabel         matlab.ui.control.Label
        UIAxes3                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        hsvImage %HSV of input image
        mask %binary image after masking
        signColor %color of the sign
        numEdge %number of edges on the sign
        shape %shape of the sign
    end
    
    methods (Access = public)
        
        function updateMask(app, value,hsvImage)
            switch (value)
                case 1 
                    % red
                    lowHue = 270/360; 
                    highHue = 360/360; 
                    lowSaturation = 0.4; 
                    highSaturation = 1; 
                    lowValue = 0.4; 
                    highValue = 1;
                    app.signColor = "Red";

                case 2 
                    % orange
                    lowHue = 0/360; 
                    highHue = 45/360; 
                    lowSaturation = 0.2; 
                    highSaturation = 1; 
                    lowValue = 0.8; 
                    highValue = 1;
                    app.signColor = "Orange";

                case 3 
                    % yellow
                    lowHue = 45/360; 
                    highHue = 90/360; 
                    lowSaturation = 0.6; 
                    highSaturation = 1.0; 
                    lowValue = 0.9; 
                    highValue = 1.0;
                    app.signColor = "Yellow";
                    
                case 4 
                    % green
                    lowHue = 70/360; 
                    highHue = 170/360; 
                    lowSaturation = 0.2; 
                    highSaturation = 1.0; 
                    lowValue = 0.2; 
                    highValue = 1.0;
                    app.signColor = "Green";

                case 5 
                    % blue
                    lowHue = 210/360; 
                    highHue = 270/360; 
                    lowSaturation = 0.6; 
                    highSaturation = 1.0; 
                    lowValue = 0.2; 
                    highValue = 1.0;
                    app.signColor = "Blue";

                case 6 
                    % brown
                    lowHue = 20/360; 
                    highHue = 40/360; 
                    lowSaturation = 0.2; 
                    highSaturation = 0.7; 
                    lowValue = 0.4; 
                    highValue = 0.6; 
                    app.signColor = "Brown";

                otherwise
                    lowHue = 0; 
                    highHue = 0; 
                    lowSaturation = 0; 
                    highSaturation = 0; 
                    lowValue = 0; 
                    highValue = 0;
            end

            app.mask = (hsvImage(:,:,1) >= lowHue & hsvImage(:,:,1) <= highHue) & ...
                   (hsvImage(:,:,2) >= lowSaturation & hsvImage(:,:,2) <= highSaturation) & ...
                   (hsvImage(:,:,3) >= lowValue & hsvImage(:,:,3) <= highValue);

            imshow(app.mask, 'Parent', app.UIAxes);
        end

        function determineSign(app, lines)
            app.ColorTextArea.Value = app.signColor;
            switch(app.numEdge)
                case 1
                    app.shape = "Others";
                case 3
                    app.shape = "Triangle";
                case 4
                    app.shape = "Square";
                    xy = [lines(1).point1; lines(1).point2];
                    len = zeros(1,4);
                    for k = 1:4
                        len(k) = norm(lines(k).point1 - lines(k).point2);
                        if abs(len(k) - len(1)) > 10
                            app.shape = "Rectangle";
                        end
                    end

                    if ~(xy(1,1) == xy(2,1) || xy(1,2) == xy(2,2))
                        app.shape = "Diamond";                        
                    end
                case 5
                    app.shape = "Pentagon";
                case 8
                    app.shape = "Octagon";
                otherwise
                    app.shape = "Circle";
                    
            end
            app.ShapeTextArea.Value = app.shape;

            switch (app.signColor)
                case 'Red'
                    switch (app.shape)
                        case 'Octagon'
                            app.MeaningTextArea.Value = "Stop. Do not enter. Wrong way. Yeah. You just, stop.";
                            app.NameCategoryTextArea.Value = "Stop Sign.";
                        case 'Triangle'
                            app.MeaningTextArea.Value = "Urgent Warning. Please be extra cautious. These signs might indicate sudden changes of terrain, accident-prone areas, etc.";
                            app.NameCategoryTextArea.Value = "Urgent Warning Signs.";
                        case 'Circle'
                            app.MeaningTextArea.Value = "These signs usually restricts what a driver can do. Either stopping entry, limiting the speed, etc.";
                            app.NameCategoryTextArea.Value = "Prohibitory/Limit Signs.";
                    end

                case 'Orange'
                    app.MeaningTextArea.Value = "These signs are usually used for construction-related warnings. Broken road, End of the pavement, etc.";
                    app.NameCategoryTextArea.Value = "Construction and Maintenance Warning Signs.";

                case 'Yellow'
                    app.MeaningTextArea.Value = "These signs are used for general warnings. There might be a speed bump, a pedestrian crossing, etc.";
                    app.NameCategoryTextArea.Value = "General Warning Signs.";

                case 'Green'
                    app.MeaningTextArea.Value = "These signs are usually used for guidance or directional information. Mostly used for navigational directions to places.";
                    app.NameCategoryTextArea.Value = "Regulatory/Guiding Signs.";

                case 'Blue'
                    app.MeaningTextArea.Value = "These signs are usually used for guidance or directional information. Mostly used for specific directions and/or parking spaces.";
                    app.NameCategoryTextArea.Value = "Regulatory/Guiding Signs.";

                case 'Brown'
                    app.MeaningTextArea.Value = "These signs are usually used to indicate areas that are for public recreation or an area that has cultural and historical significance.";
                    app.NameCategoryTextArea.Value = "Public Recreation Signs.";
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)

            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            image=imread(filename);
            imshow(image,'Parent',app.UIAxes2);
            
            app.Slider.Enable = "on";
            app.SelectMaskButton.Enable = "on";
            app.Slider.Value = 1;
            app.hsvImage = rgb2hsv(image);
            updateMask(app,1, app.hsvImage)
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            
            updateMask(app,value, app.hsvImage)
        end

        % Button pushed function: SelectMaskButton
        function SelectMaskButtonPushed(app, event)
            found = false;
            [row, col] = size(app.mask);
            signEdge = zeros(row,col);
            % getting first white pixel
            for i = 1:row
                for j = 1:col
                    if app.mask(i,j)
                        pt = [i j];
                        r = i;
                        c = j;
                        signEdge(i,j) = 1;
                        found = true;
                        break
                    end
                end
                if found
                    break
                end
            end
            
            %getting second reference point
            z=7;
            while true
                if z == 0 || z == 4
                    tRow = r;
                elseif z > 0 && z < 4
                    tRow = r-1;
                else
                    tRow = r+1;
                end
            
                if z == 2 || z == 6
                    tCol = c;
                elseif z > 2 && z < 6
                    tCol = c-1;
                else
                    tCol = c+1;
                end
            
                if app.mask(tRow,tCol)
                    r = tRow;
                    c = tCol;
                    pt2 = [r c];
                    signEdge(r,c) = 1;
                    if mod(z,2) == 0
                        z = mod(z+7,8);
                    else
                        z = mod(z+6,8);
                    end
                    break
                else
                    if z == 7
                        z = 0;
                    else
                        z = z+1;
                    end
                end
            end
            prevPt = [0 0];
            currPt = [0 0];
            if mod(z,2) == 1
                z = mod(z+6,8);
            else
                z = mod(z+7,8);
            end
            ctr = 0;
            while true
                if z == 0 || z == 4
                    tRow = r;
                elseif z > 0 && z < 4
                    tRow = r-1;
                else
                    tRow = r+1;
                end
            
                if z == 2 || z == 6
                    tCol = c;
                elseif z > 2 && z < 6
                    tCol = c-1;
                else
                    tCol = c+1;
                end
            
                if app.mask(tRow,tCol)
                    r = tRow;
                    c = tCol;
                    prevPt = currPt;
                    currPt = [r c];
                    signEdge(r,c) = 1;
                    if mod(z,2) == 1
                        z = mod(z+6,8);
                    else
                        z = mod(z+7,8);
                    end
                else
                    if z == 7
                        z = 0;
                    else
                        z = z+1;
                    end
                end

                if ctr == row*col
                    break
                end
                ctr = ctr + 1;
            end
            [height, width] = size(signEdge);
            %% using built in hough transform in matlab
            tic
            [H,T,R] = hough(signEdge);
            toc
            P  = houghpeaks(H, 30, 'threshold', ceil(0.4 * max(H(:))));
            x = T(P(:,2));
            y = R(P(:,1));
            lines = houghlines(signEdge, T, R, P, 'FillGap', 120, 'MinLength', 10);

            figure;
            imshow(imadjust(mat2gray(H)), 'XData', T, 'YData', R, 'InitialMagnification', 'fit');
            title('Hough Transform of the Image (MATLAB)');
            xlabel('\theta (degrees)');
            ylabel('\rho');
            axis on;
            axis normal;
            colormap(gca, 'gray');
            colorbar; 
            
            % Plot peaks on Hough Transform
            hold on;
            plot(T(P(:, 2)), R(P(:, 1)), 's', 'color', 'red');
            hold off;
            
            figure; hold on
            
            max_len = 0;
            for k = 1:length(lines)
               xy = [lines(k).point1; lines(k).point2];
               plot(xy(:,1),xy(:,2),'LineWidth',2,'Color','green');

               % Plot beginnings and ends of lines
               plot(xy(1,1),xy(1,2),'x','LineWidth',2,'Color','yellow');
               plot(xy(2,1),xy(2,2),'x','LineWidth',2,'Color','red');
            end

            %% counting edges using hough transform (C)


            [Hi, ~, MR] = cMex(uint8(signEdge), width, height);
            imwrite(Hi, 'hough_transformMatlab-C.pgm');
            H = double(Hi');

            T = -90:89; 
            R = -MR:MR;

            P  = houghpeaks(H, 30, 'threshold', ceil(0.4 * max(H(:))));
            x = T(P(:,2));
            y = R(P(:,1));
            lines = houghlines(signEdge, T, R, P, 'FillGap', 120, 'MinLength', 10);

            figure;
            imshow(imadjust(mat2gray(H)), 'XData', T, 'YData', R, 'InitialMagnification', 'fit');
            title('Hough Transform of the Image (C)');
            xlabel('\theta (degrees)');
            ylabel('\rho');
            axis on;
            axis normal;
            colormap(gca, 'gray');
            colorbar; 
            
            % Plot peaks on Hough Transform
            hold on;
            plot(T(P(:, 2)), R(P(:, 1)), 's', 'color', 'red');
            hold off;
            
            figure; hold on
            
            max_len = 0;
            for k = 1:length(lines)
               xy = [lines(k).point1; lines(k).point2];
               plot(xy(:,1),xy(:,2),'LineWidth',2,'Color','green');

               % Plot beginnings and ends of lines
               plot(xy(1,1),xy(1,2),'x','LineWidth',2,'Color','yellow');
               plot(xy(2,1),xy(2,2),'x','LineWidth',2,'Color','red');
            end

            app.numEdge = length(lines);
            determineSign(app,lines)
%%----------------------------------------------------------------------------------%%
            %% counting edges using hough transform (CUDA)

            [height, width] = size(signEdge);

            [Hi, ~, MR] = cudaMex(uint8(signEdge), width, height);
            imwrite(Hi, 'hough_transformMatlab-Cuda.pgm');
            H = double(Hi');

            T = -90:89; 
            R = -MR:MR;

            P  = houghpeaks(H, 30, 'threshold', ceil(0.4 * max(H(:))));
            x = T(P(:,2));
            y = R(P(:,1));
            lines = houghlines(signEdge, T, R, P, 'FillGap', 120, 'MinLength', 10);

            figure;
            imshow(imadjust(mat2gray(H)), 'XData', T, 'YData', R, 'InitialMagnification', 'fit');
            title('Hough Transform of the Image (CUDA)');
            xlabel('\theta (degrees)');
            ylabel('\rho');
            axis on;
            axis normal;
            colormap(gca, 'gray');
            colorbar; 
            
            % Plot peaks on Hough Transform
            hold on;
            plot(T(P(:, 2)), R(P(:, 1)), 's', 'color', 'red');
            hold off;
            
            % Overlay detected lines on the original image
            figure;
            imshow(signEdge);
            title('Detected Lines');
            hold on;

            %% comment this out after testing
            figure; hold on
            
            max_len = 0;
            for k = 1:length(lines)
               xy = [lines(k).point1; lines(k).point2];
               plot(xy(:,1),xy(:,2),'LineWidth',2,'Color','green');

               % Plot beginnings and ends of lines
               plot(xy(1,1),xy(1,2),'x','LineWidth',2,'Color','yellow');
               plot(xy(2,1),xy(2,2),'x','LineWidth',2,'Color','red');
            end

            app.numEdge = length(lines);
            determineSign(app,lines)

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {662, 662, 662};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {662, 662};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {425, '1x', 367};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.LeftPanel);
            title(app.UIAxes2, 'Original Image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UploadImageButton = uibutton(app.LeftPanel, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.TRAFFICSIGNDETECTORLabel = uilabel(app.LeftPanel);
            app.TRAFFICSIGNDETECTORLabel.Text = {'TRAFFIC SIGN'; 'DETECTOR'};
            app.Group5Label = uilabel(app.LeftPanel);
            app.Group5Label.Text = 'Group 5';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Binary Mask')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SelecttheBinaryMaskthatbesthighlightthetrafficsignLabel = uilabel(app.CenterPanel);
            app.SelecttheBinaryMaskthatbesthighlightthetrafficsignLabel.Text = {'Select the Binary Mask that best '; 'highlight the traffic sign'};
            app.SelectMaskButton = uibutton(app.CenterPanel, 'push');
            app.SelectMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMaskButtonPushed, true);
            app.SelectMaskButton.Enable = 'off';
            app.SelectMaskButton.Text = 'Select Mask';
            app.Label = uilabel(app.CenterPanel);
            app.Label.Enable = 'off';
            app.Label.Text = '';
            app.Slider = uislider(app.CenterPanel);
            app.Slider.Limits = [1 6];
            app.Slider.MajorTicks = [1 2 3 4 5 6];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Slider.Enable = 'off';
            app.Slider.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Possible Sign Matches')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.ColorTextAreaLabel = uilabel(app.RightPanel);
            app.ColorTextAreaLabel.Text = 'Color';
            app.ColorTextArea = uitextarea(app.RightPanel);
            app.ColorTextArea.Editable = 'off';
            app.ShapeTextAreaLabel = uilabel(app.RightPanel);
            app.ShapeTextAreaLabel.Text = 'Shape';
            app.ShapeTextArea = uitextarea(app.RightPanel);
            app.ShapeTextArea.Editable = 'off';
            app.NameCategoryTextAreaLabel = uilabel(app.RightPanel);
            app.NameCategoryTextAreaLabel.Text = 'Name/Category';
            app.NameCategoryTextArea = uitextarea(app.RightPanel);
            app.NameCategoryTextArea.Editable = 'off';
            app.MeaningTextAreaLabel = uilabel(app.RightPanel);
            app.MeaningTextAreaLabel.Text = 'Meaning';
            app.MeaningTextArea = uitextarea(app.RightPanel);
            app.MeaningTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end